classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SettingsLabel                 matlab.ui.control.Label
        backgroundcolorDropDown       matlab.ui.control.DropDown
        backgroundcolorDropDownLabel  matlab.ui.control.Label
        Inertia1EditField             matlab.ui.control.EditField
        Inertia1EditFieldLabel        matlab.ui.control.Label
        Inertia3EditField             matlab.ui.control.EditField
        Inertia3EditFieldLabel        matlab.ui.control.Label
        Inertia2EditField             matlab.ui.control.EditField
        Inertia2EditFieldLabel        matlab.ui.control.Label
        Switch                        matlab.ui.control.ToggleSwitch
        SwitchLabel                   matlab.ui.control.Label
        animationwithpenButton        matlab.ui.control.Button
        animationwithdynamicsButton   matlab.ui.control.Button
        animationwithobstaclesButton  matlab.ui.control.Button
        AnimationLabel                matlab.ui.control.Label
        animationButton               matlab.ui.control.Button
        O3EditField                   matlab.ui.control.EditField
        O3EditFieldLabel              matlab.ui.control.Label
        O2EditField                   matlab.ui.control.EditField
        O2EditFieldLabel              matlab.ui.control.Label
        O1EditField                   matlab.ui.control.EditField
        O1EditFieldLabel              matlab.ui.control.Label
        inverseButton                 matlab.ui.control.Button
        pzEditField                   matlab.ui.control.EditField
        pzEditFieldLabel              matlab.ui.control.Label
        pyEditField                   matlab.ui.control.EditField
        pyEditFieldLabel              matlab.ui.control.Label
        pxEditField                   matlab.ui.control.EditField
        pxEditFieldLabel              matlab.ui.control.Label
        inversekinematicsLabel        matlab.ui.control.Label
        ForwardkinematicsLabel        matlab.ui.control.Label
        PositionLabel                 matlab.ui.control.Label
        DefaultButton                 matlab.ui.control.Button
        DOFROBOTICARMLabel            matlab.ui.control.Label
        forwardButton                 matlab.ui.control.Button
        zEditField                    matlab.ui.control.EditField
        zEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.EditField
        yEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.EditField
        xEditFieldLabel               matlab.ui.control.Label
        theta3EditField               matlab.ui.control.EditField
        theta3EditFieldLabel          matlab.ui.control.Label
        theta2EditField               matlab.ui.control.EditField
        theta2EditFieldLabel          matlab.ui.control.Label
        theta1EditField               matlab.ui.control.EditField
        theta1EditFieldLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
     % === Read joint angles from UI (degrees) and convert to radians ===
theta1 = str2double(app.theta1EditField.Value);  % Read angle θ1 from UI (text box) and convert to numeric (degrees)
theta2 = str2double(app.theta2EditField.Value);  % Read angle θ2 from UI and convert to double
theta3 = str2double(app.theta3EditField.Value);  % Read angle θ3 from UI and convert to double

t1 = deg2rad(theta1);  % Convert θ1 from degrees to radians (used in trigonometric functions)
t2 = deg2rad(theta2);  % Convert θ2 from degrees to radians
t3 = deg2rad(theta3);  % Convert θ3 from degrees to radians

% === Link lengths ===
L1 = 1.5;  % Length of first link (from base to joint 1)
L2 = 1.5;  % Length of second link (from joint 1 to joint 2)
L3 = 1.5;  % Length of third link (from joint 2 to end-effector)

% === Transformation matrices ===
% T01: Transformation from base frame to joint 1 (rotation about Z and translation along Z)
T01 = [cos(t1), -sin(t1), 0, 0;
       sin(t1),  cos(t1), 0, 0;
       0,        0,       1, L1;
       0,        0,       0, 1];

% T12: Transformation from joint 1 to joint 2 (rotation about Y and translation along X & Z)
T12 = [cos(t2), 0, sin(t2), L2*cos(t2);
       0,       1, 0,       0;
      -sin(t2), 0, cos(t2), L2*sin(t2);
       0,       0, 0,       1];

% T23: Transformation from joint 2 to joint 3 (rotation about Y and translation along X & Z)
T23 = [cos(t3), 0, sin(t3), L3*cos(t3);
       0,       1, 0,       0;
      -sin(t3), 0, cos(t3), L3*sin(t3);
       0,       0, 0,       1];

% === Forward Kinematics ===
T02 = T01 * T12;  % Combine transformations: base to joint 2
T03 = T02 * T23;  % Combine transformations: base to end-effector

% Extract position vectors from transformation matrices
O0 = [0; 0; 0];             % Base position in global frame
O1 = T01(1:3,4);            % Position of joint 1
O2 = T02(1:3,4);            % Position of joint 2
O3 = T03(1:3,4);            % Position of end-effector

% === Display End-Effector Position ===
app.xEditField.Value = num2str(O3(1), '%.2f');  % Display X coordinate in UI (rounded to 2 decimal places)
app.yEditField.Value = num2str(O3(2), '%.2f');  % Display Y coordinate
app.zEditField.Value = num2str(O3(3), '%.2f');  % Display Z coordinate

% === UIAxes Setup ===
cla(app.UIAxes);               % Clear the axes
hold(app.UIAxes, 'on');        % Hold for drawing multiple elements
axis(app.UIAxes, 'equal');     % Set equal scaling for all axes
view(app.UIAxes, 3);           % Set 3D view
grid(app.UIAxes, 'on');        % Turn on grid
xlabel(app.UIAxes, 'X');       % Label X axis
ylabel(app.UIAxes, 'Y');       % Label Y axis
zlabel(app.UIAxes, 'Z');       % Label Z axis
xlim(app.UIAxes, [-4 4]);      % Limit X-axis range
ylim(app.UIAxes, [-4 4]);      % Limit Y-axis range
zlim(app.UIAxes, [0 5]);       % Limit Z-axis range
camlight(app.UIAxes, 'headlight');  % Add camera light for shading
lighting(app.UIAxes, 'gouraud');    % Smooth lighting model

% === Custom radii for each link ===
r1 = 0.4;  % Radius of cylinder from base to joint 1 (Red color)
r2 = 0.3;  % Radius from joint 1 to joint 2 (Green)
r3 = 0.2;  % Radius from joint 2 to end-effector (Blue)

% === Draw robot arm as colored cylinders ===
drawLinkCylinder(app, app.UIAxes, O0, O1, r1, [1 0 0]);  % Draw red cylinder for first link
drawLinkCylinder(app, app.UIAxes, O1, O2, r2, [0 1 0]);  % Draw green cylinder for second link
drawLinkCylinder(app, app.UIAxes, O2, O3, r3, [0 0 1]);  % Draw blue cylinder for third link

end


function drawLinkCylinder(app, ax, p1, p2, radius, color)
    h = norm(p2 - p1);  % Compute length (height) of cylinder between p1 and p2
    if h < 1e-6, return; end  % If link is too short, skip drawing

    [X, Y, Z] = cylinder(radius, 20);  % Generate circular base with radius and 20 segments
    Z = Z * h;  % Stretch cylinder along Z to correct height

    dir = (p2 - p1) / h;  % Direction unit vector from p1 to p2
    zAxis = [0; 0; 1];    % Default direction of cylinder
    v = cross(zAxis, dir);  % Axis of rotation between z-axis and desired direction
    s = norm(v);           % Sine of rotation angle (magnitude of rotation vector)
    c = dot(zAxis, dir);   % Cosine of rotation angle (dot product of direction vectors)

    if s < 1e-6
        R = eye(3);  % No rotation needed (direction aligned with Z)
    else
        % Skew-symmetric matrix for cross-product
        vx = [  0,   -v(3),  v(2);
               v(3),  0,   -v(1);
              -v(2), v(1),   0 ];
        % Rodrigues’ rotation formula to compute 3D rotation matrix
        R = eye(3) + vx + vx^2 * ((1 - c) / s^2);
    end

    % Apply rotation and translation to every point on the cylinder surface
    for i = 1:size(X, 1)
        for j = 1:size(X, 2)
            pt = R * [X(i,j); Y(i,j); Z(i,j)];  % Rotate point
            X(i,j) = pt(1) + p1(1);             % Translate X to start from p1
            Y(i,j) = pt(2) + p1(2);             % Translate Y
            Z(i,j) = pt(3) + p1(3);             % Translate Z
        end
    end

    % Draw the 3D surface on the UI axes
    surf(ax, X, Y, Z, 'FaceColor', color, 'EdgeColor', 'none');      


        end

        % Value changing function: theta1EditField
        function theta1EditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: theta2EditField
        function theta2EditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: theta3EditField
        function theta3EditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: xEditField
        function xEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: yEditField
        function yEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: zEditField
        function zEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: inverseButton
        function inverseButtonPushed(app, event)

  % === Read target position from UI ===
px = str2double(app.pxEditField.Value);  % Convert x target input from string to number
py = str2double(app.pyEditField.Value);  % Convert y target input
pz = str2double(app.pzEditField.Value);  % Convert z target input

% === Link lengths ===
L1 = 1.5; L2 = 1.5; L3 = 1.5;  % Lengths of robot arm links

% === Inverse Kinematics ===
r = sqrt(px^2 + py^2);  % Compute radial distance in XY plane
z = pz - L1;  % Adjust Z position by subtracting base height

D = (r^2 + z^2 - L2^2 - L3^2) / (2 * L2 * L3);  % Law of cosines to find elbow angle
if abs(D) > 1  % Check if target is outside reachable workspace
    uialert(app.UIFigure, 'Target is unreachable', 'Error');  % Show error message
    return;  % Stop function
end

theta3 = atan2(sqrt(1 - D^2), D);  % Elbow joint angle
theta2 = atan2(z, r) - atan2(L3 * sin(theta3), L2 + L3 * cos(theta3));  % Shoulder joint angle
theta1 = atan2(py, px);  % Base joint angle

% === Update angle fields in UI ===
app.theta1EditField.Value = num2str(rad2deg(theta1));  % Show theta1 in degrees
app.theta2EditField.Value = num2str(rad2deg(theta2));
app.theta3EditField.Value = num2str(rad2deg(theta3));

% === Forward Kinematics ===
T01 = [cos(theta1), -sin(theta1), 0, 0;
       sin(theta1),  cos(theta1), 0, 0;
       0,            0,           1, L1;
       0,            0,           0, 1];  % Transformation from base to joint 1

T12 = [cos(theta2), 0, sin(theta2), L2*cos(theta2);
       0,           1, 0,           0;
      -sin(theta2), 0, cos(theta2), L2*sin(theta2);
       0,           0, 0,           1];  % Joint 1 to joint 2

T23 = [cos(theta3), 0, sin(theta3), L3*cos(theta3);
       0,           1, 0,           0;
      -sin(theta3), 0, cos(theta3), L3*sin(theta3);
       0,           0, 0,           1];  % Joint 2 to end-effector

T02 = T01 * T12;  % Combined transform to joint 2
T03 = T02 * T23;  % Combined transform to end effector

O0 = [0; 0; 0];         % Origin of base
O1 = T01(1:3, 4);       % Position of joint 1
O2 = T02(1:3, 4);       % Position of joint 2
O3 = T03(1:3, 4);       % Position of end effector

% === Display End-Effector Position in UI ===
app.xEditField.Value = num2str(O3(1), '%.2f');  % Show x of end effector
app.yEditField.Value = num2str(O3(2), '%.2f');  % Show y of end effector
app.zEditField.Value = num2str(O3(3), '%.2f');  % Show z of end effector

% === Plotting Section ===
cla(app.UIAxes);  % Clear previous drawing
hold(app.UIAxes, 'on');
axis(app.UIAxes, 'equal');
view(app.UIAxes, 3);
grid(app.UIAxes, 'on');
xlabel(app.UIAxes, 'X');
ylabel(app.UIAxes, 'Y');
zlabel(app.UIAxes, 'Z');

% === Set dynamic axis range based on reach ===
reach = L1 + L2 + L3 + 1;  % Define plotting boundary
xlim(app.UIAxes, [-reach, reach]);
ylim(app.UIAxes, [-reach, reach]);
zlim(app.UIAxes, [0, reach]);

camlight(app.UIAxes, 'headlight');  % Lighting from camera
lighting(app.UIAxes, 'gouraud');    % Smooth lighting

% === Draw Cylindrical Links ===
drawCylinder(app, O0, O1, 0.4, [1 0 0]);  % Red cylinder base to joint 1
drawCylinder(app, O1, O2, 0.3, [0 1 0]);  % Green cylinder joint 1 to 2
drawCylinder(app, O2, O3, 0.2, [0 0 1]);  % Blue cylinder joint 2 to end effector

% === Local Function for Drawing a Link Cylinder ===
function drawCylinder(app, p1, p2, radius, color)
    h = norm(p2 - p1);  % Length of the link
    if h < 1e-6, return; end  % Skip if link is too short

    [X, Y, Z] = cylinder(radius, 20);  % Create circular cylinder mesh
    Z = Z * h;  % Scale height of cylinder

    dir = (p2 - p1) / h;  % Direction vector
    zAxis = [0; 0; 1];  % Reference z-axis
    v = cross(zAxis, dir);  % Axis of rotation
    s = norm(v);
    c = dot(zAxis, dir);  % Cosine of angle between z and dir

    if s < 1e-6
        R = eye(3);  % No rotation needed
    else
        vx = [0, -v(3), v(2);
              v(3), 0, -v(1);
             -v(2), v(1), 0];  % Skew-symmetric matrix
        R = eye(3) + vx + vx^2 * ((1 - c) / s^2);  % Rodrigues rotation
    end

    for i = 1:size(X,1)
        for j = 1:size(X,2)
            pt = R * [X(i,j); Y(i,j); Z(i,j)];  % Apply rotation
            X(i,j) = pt(1) + p1(1);  % Translate
            Y(i,j) = pt(2) + p1(2);
            Z(i,j) = pt(3) + p1(3);
        
    end
end
    surf(app.UIAxes, X, Y, Z, 'FaceColor', color, 'EdgeColor', 'none');  % Draw surface

        end


        end

        % Button pushed function: animationwithobstaclesButton
        function animationwithobstaclesButtonPushed(app, event)

L1 = 1.5; L2 = 1.5; L3 = 1.5;
steps = 10;
dt = 0.9;  % read dt from slider in GUI

% Radii of robot links
linkRadii = [0.4, 0.3, 0.2];

% Read target angles in degrees and convert to radians
theta1_target = deg2rad(str2double(app.O1EditField.Value));
theta2_target = deg2rad(str2double(app.O2EditField.Value));
theta3_target = deg2rad(str2double(app.O3EditField.Value));

% Linear interpolation for smooth animation
theta1_traj = linspace(0, theta1_target, steps);
theta2_traj = linspace(0, theta2_target, steps);
theta3_traj = linspace(0, theta3_target, steps);

% Define obstacles (spheres), farther apart
obstacleCenters = [1.5, 0.5, 1; -1.5, -0.5, 1.2];
obstacleRadii = [0.3; 0.4];

for i = 1:steps
    % Current joint angles
    theta1 = theta1_traj(i);
    theta2 = theta2_traj(i);
    theta3 = theta3_traj(i);

    % Forward kinematics: calculate joint positions
    O0 = [0;0;0];
    O1 = [0;0;L1];
    Rz1 = [cos(theta1), -sin(theta1), 0;
           sin(theta1),  cos(theta1), 0;
           0, 0, 1];
    P2_local = [L2*cos(theta2); 0; L2*sin(theta2)];
    O2 = O1 + Rz1 * P2_local;
    P3_local = [L3*cos(theta2 + theta3); 0; L3*sin(theta2 + theta3)];
    O3 = O2 + Rz1 * P3_local;

    % Update end-effector coordinates in the app (2 decimals)
    app.xEditField.Value = num2str(O3(1), '%.2f');
    app.yEditField.Value = num2str(O3(2), '%.2f');
    app.zEditField.Value = num2str(O3(3), '%.2f');

    % Clear and set up axes
    cla(app.UIAxes);
    axis(app.UIAxes, 'equal');
    view(app.UIAxes, 3);
    grid(app.UIAxes, 'on');
    xlim(app.UIAxes, [-3 3]);
    ylim(app.UIAxes, [-3 3]);
    zlim(app.UIAxes, [0 4]);
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    zlabel(app.UIAxes, 'Z');
    hold(app.UIAxes, 'on');

% === Draw  obstacles as semi-transparent spheres
    [sx, sy, sz] = sphere(20);% Generate the x, y, z coordinates of a unit sphere with resolution 20 (more points = smoother sphere)
    for k = 1:size(obstacleCenters,1)% Loop over each obstacle defined by its center and radius
        surf(app.UIAxes, obstacleRadii(k)*sx + obstacleCenters(k,1), ...% Scale x-coordinates of the unit sphere by the obstacle radius and shift by x-position of the center
                       obstacleRadii(k)*sy + obstacleCenters(k,2), ...% Scale y-coordinates and shift to y-center
                       obstacleRadii(k)*sz + obstacleCenters(k,3), ...% Scale z-coordinates and shift to z-center
             'FaceColor','k','FaceAlpha',0.5,'EdgeColor','none'); % Set the color of the obstacle to black
    end

    % Draw robot links as cylinders
    drawLinkCylinder(app.UIAxes, O0, O1, linkRadii(1), [1 0 0]);
    drawLinkCylinder(app.UIAxes, O1, O2, linkRadii(2), [0 1 0]);
    drawLinkCylinder(app.UIAxes, O2, O3, linkRadii(3), [0 0 1]);

    drawnow;

    % Check collisions for each link
    collision = checkCollision(O0, O1, obstacleCenters, obstacleRadii, linkRadii(1)) || ...
                checkCollision(O1, O2, obstacleCenters, obstacleRadii, linkRadii(2)) || ...
                checkCollision(O2, O3, obstacleCenters, obstacleRadii, linkRadii(3));

    if collision
        % Show the last collided pose
        hold(app.UIAxes,'off');
        drawnow;
        uialert(app.UIFigure, 'Collision detected! Animation stopped.', 'Collision Warning');
        return;  % Stop animation
    end
    hold(app.UIAxes, 'off');
    pause(dt);
end

% Helper function to draw a cylinder between two points
function drawLinkCylinder(ax, p1, p2, radius, color)
    h = norm(p2 - p1);
    if h < 1e-6, return; end

    [X, Y, Z] = cylinder(radius, 20);
    Z = Z * h;

    dir = (p2 - p1) / h;
    zAxis = [0; 0; 1];
    v = cross(zAxis, dir);
    s = norm(v);
    c = dot(zAxis, dir);

    if s < 1e-6
        R = eye(3);
    else
        vx = [  0, -v(3), v(2);
               v(3),  0, -v(1);
              -v(2), v(1),  0];
        R = eye(3) + vx + vx^2 * ((1 - c) / s^2);
    end

    for i = 1:size(X, 1)
        for j = 1:size(X, 2)
            pt = R * [X(i,j); Y(i,j); Z(i,j)];
            X(i,j) = pt(1) + p1(1);
            Y(i,j) = pt(2) + p1(2);
            Z(i,j) = pt(3) + p1(3);
        end
    end

    surf(ax, X, Y, Z, 'FaceColor', color, 'EdgeColor', 'none');
end

% Collision detection helper (distance between line segment and sphere center)
%Function to check if a robot link collides with any obstacle ===
function isCollision = checkCollision(p1, p2, centers, radiiSph, radiusLink)

    isCollision = false;  
    % Initialize collision flag to false (no collision by default)

    for j = 1:size(centers,1)  
        % Loop through each obstacle (each row in 'centers' matrix)

        dist = pointToSegmentDist(centers(j,:)', p1, p2);  
        % Compute distance between obstacle center and the robot link segment [p1 to p2]

        if dist < (radiiSph(j) + radiusLink)  
            % If this distance is smaller than the sum of obstacle and link radii → collision detected

            isCollision = true;  
            % Set collision flag to true

            return;  
            % Exit early since one collision is enough to stop animation
        end
    end
end


% === Function to compute shortest distance from a point to a line segment ===
function dist = pointToSegmentDist(P, A, B)

    AB = B - A;  
    % Vector from point A to point B (link vector)

    AP = P - A;  
    % Vector from point A to obstacle center P

    t = dot(AP, AB) / dot(AB, AB);  
    % Project AP onto AB using dot product to find scalar projection 't'

    t = max(0, min(1, t));  
    % Clamp t between 0 and 1 to ensure closest point lies on the segment [A, B]

    closest = A + t * AB;  
    % Compute the closest point on the segment to point P

    dist = norm(P - closest);  
    % Compute the Euclidean distance from point P to that closest point
end
        end

        % Button pushed function: animationwithdynamicsButton
        function animationwithdynamicsButtonPushed(app, event)
             % Link lengths
    L1 = 1.5; L2 = 1.5; L3 = 1.5;
    steps = 10;
    dt = 0.9;

    % Read joint target angles (degrees to radians)
    theta1_target = deg2rad(str2double(app.O1EditField.Value));
    theta2_target = deg2rad(str2double(app.O2EditField.Value));
    theta3_target = deg2rad(str2double(app.O3EditField.Value));

    % Read inertia values
    I1 = str2double(app.Inertia1EditField.Value);
    I2 = str2double(app.Inertia2EditField.Value);
    I3 = str2double(app.Inertia3EditField.Value);

    % Validate inputs
    if any(isnan([theta1_target, theta2_target, theta3_target, I1, I2, I3])) || ... Check if any of the input values are NaN (i.e., missing or invalid). This ensures that all joint angles and inertia values have been properly entered before proceeding.
       any([I1, I2, I3] <= 0)
        uialert(app.UIFigure, 'Please enter valid numeric and positive inertia values.', 'Input Error');
        return;
    end

    % Generate joint angle trajectories
    theta1_traj = linspace(0, theta1_target, steps);
    theta2_traj = linspace(0, theta2_target, steps);
    theta3_traj = linspace(0, theta3_target, steps);

    % Initialize arrays
    w1 = zeros(1, steps); w2 = w1; w3 = w1;
    torque1 = w1; torque2 = w1; torque3 = w1;

    % Animation loop
    for i = 1:steps
        theta1 = theta1_traj(i);
        theta2 = theta2_traj(i);
        theta3 = theta3_traj(i);

        if i > 1
            % Angular velocity
            w1(i) = (theta1_traj(i) - theta1_traj(i-1)) / dt;
            w2(i) = (theta2_traj(i) - theta2_traj(i-1)) / dt;
            w3(i) = (theta3_traj(i) - theta3_traj(i-1)) / dt;

            % Torque = I * angular acceleration
            torque1(i) = I1 * (w1(i) - w1(i-1)) / dt;
            torque2(i) = I2 * (w2(i) - w2(i-1)) / dt;
            torque3(i) = I3 * (w3(i) - w3(i-1)) / dt;
        end

        % Forward kinematics
        O0 = [0; 0; 0];
        O1 = [0; 0; L1];
        Rz1 = [cos(theta1), -sin(theta1), 0;
               sin(theta1),  cos(theta1), 0;
               0,           0,            1];

        P2_local = [L2*cos(theta2); 0; L2*sin(theta2)];
        O2 = O1 + Rz1 * P2_local;

        P3_local = [L3*cos(theta2 + theta3); 0; L3*sin(theta2 + theta3)];
        O3 = O2 + Rz1 * P3_local;

        % Update output position
        app.xEditField.Value = num2str(O3(1), '%.2f');
        app.yEditField.Value = num2str(O3(2), '%.2f');
        app.zEditField.Value = num2str(O3(3), '%.2f');

        % Clear and draw on UIAxes
        cla(app.UIAxes);
        hold(app.UIAxes, 'on');
        axis(app.UIAxes, 'equal');
        view(app.UIAxes, 3);
        grid(app.UIAxes, 'on');
        xlim(app.UIAxes, [-3 3]);
        ylim(app.UIAxes, [-3 3]);
        zlim(app.UIAxes, [0 4]);
        xlabel(app.UIAxes, 'X');
        ylabel(app.UIAxes, 'Y');
        zlabel(app.UIAxes, 'Z');
        camlight(app.UIAxes, 'headlight');
        lighting(app.UIAxes, 'gouraud');

        % Draw links
        drawLinkCylinder(app.UIAxes, O0, O1, 0.4, [1 0 0]);
        drawLinkCylinder(app.UIAxes, O1, O2, 0.3, [0 1 0]);
        drawLinkCylinder(app.UIAxes, O2, O3, 0.2, [0 0 1]);

        drawnow;
        pause(dt);
    end

    % Plot torque and velocity
    t = (0:steps-1) * dt;
    figure('Name', 'Torque & Velocity');

    subplot(2,1,1);
    plot(t, torque1, 'r', t, torque2, 'g', t, torque3, 'b');
    title('Joint Torques'); grid on;
    xlabel('Time (s)'); ylabel('Torque (Nm)');
    legend('τ1', 'τ2', 'τ3');

    subplot(2,1,2);
    plot(t, w1, 'r', t, w2, 'g', t, w3, 'b');
    title('Joint Velocities'); grid on;
    xlabel('Time (s)'); ylabel('Velocity (rad/s)');
    legend('ω1', 'ω2', 'ω3');
    
    function drawLinkCylinder(ax, p1, p2, radius, color)
        h = norm(p2 - p1);
        if h < 1e-6, return; end
        [X, Y, Z] = cylinder(radius, 20);
        Z = Z * h;

        dir = (p2 - p1) / h;
        zAxis = [0; 0; 1];
        v = cross(zAxis, dir);
        s = norm(v);
        c = dot(zAxis, dir);

        if s < 1e-6
            R = eye(3);
        else
            vx = [0, -v(3), v(2);
                  v(3), 0, -v(1);
                 -v(2), v(1), 0];
            R = eye(3) + vx + vx^2 * ((1 - c) / s^2);
        end

        for i = 1:size(X, 1)
            for j = 1:size(X, 2)
                pt = R * [X(i,j); Y(i,j); Z(i,j)];
                X(i,j) = pt(1) + p1(1);
                Y(i,j) = pt(2) + p1(2);
                Z(i,j) = pt(3) + p1(3);
            end
        end

        surf(ax, X, Y, Z, 'FaceColor', color, 'EdgeColor', 'none');
    end

        end

        % Button pushed function: animationButton
        function animationButtonPushed(app, event)
            % === Clear UIAxes ===
cla(app.UIAxes, 'reset');
axis(app.UIAxes, 'equal');
grid(app.UIAxes, 'on');
view(app.UIAxes, 3);
xlim(app.UIAxes, [-3 3]);
ylim(app.UIAxes, [-3 3]);
zlim(app.UIAxes, [0 4]);
xlabel(app.UIAxes, 'X');
ylabel(app.UIAxes, 'Y');
zlabel(app.UIAxes, 'Z');
hold(app.UIAxes, 'on');

% === Parameters ===
L1 = 1.5; L2 = 1.5; L3 = 1.5;
linkRadii = [0.4, 0.3, 0.2];
steps = 10;
dt = 0.9;

% === Target angles in radians ===
theta1_target = deg2rad(str2double(app.O1EditField.Value));
theta2_target = deg2rad(str2double(app.O2EditField.Value));
theta3_target = deg2rad(str2double(app.O3EditField.Value));

% === Interpolation ===
theta1_traj = linspace(0, theta1_target, steps);
theta2_traj = linspace(0, theta2_target, steps);
theta3_traj = linspace(0, theta3_target, steps);

% === Animation Loop ===
for i = 1:steps
    theta1 = theta1_traj(i);
    theta2 = theta2_traj(i);
    theta3 = theta3_traj(i);

    % Forward Kinematics
    O0 = [0; 0; 0];
    O1 = [0; 0; L1];
    Rz1 = [cos(theta1), -sin(theta1), 0;
           sin(theta1),  cos(theta1), 0;
           0, 0, 1];
    P2 = Rz1 * [L2 * cos(theta2); 0; L2 * sin(theta2)];
    O2 = O1 + P2;
    P3 = Rz1 * [L3 * cos(theta2 + theta3); 0; L3 * sin(theta2 + theta3)];
    O3 = O2 + P3;

    % Update end-effector position in GUI
    app.xEditField.Value = num2str(O3(1), '%.2f');
    app.yEditField.Value = num2str(O3(2), '%.2f');
    app.zEditField.Value = num2str(O3(3), '%.2f');

    % Clear previous frame
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');

    % Draw robot links
    drawLinkCylinder(app.UIAxes, O0, O1, linkRadii(1), [1 0 0]);
    drawLinkCylinder(app.UIAxes, O1, O2, linkRadii(2), [0 1 0]);
    drawLinkCylinder(app.UIAxes, O2, O3, linkRadii(3), [0 0 1]);

    drawnow;
    pause(dt);
end

% === Helper: Draw cylinder between two 3D points ===
function drawLinkCylinder(ax, p1, p2, radius, color)
    h = norm(p2 - p1);
    if h < 1e-6, return; end
    [X, Y, Z] = cylinder(radius, 20);
    Z = Z * h;

    dir = (p2 - p1) / h;
    zAxis = [0; 0; 1];
    v = cross(zAxis, dir);
    s = norm(v);
    c = dot(zAxis, dir);

    if s < 1e-6
        R = eye(3);
    else
        vx = [  0, -v(3), v(2);
               v(3),  0, -v(1);
              -v(2), v(1),  0];
        R = eye(3) + vx + vx^2 * ((1 - c) / s^2);
    end

    for i = 1:size(X,1)
        for j = 1:size(X,2)
            pt = R * [X(i,j); Y(i,j); Z(i,j)];
            X(i,j) = pt(1) + p1(1);
            Y(i,j) = pt(2) + p1(2);
            Z(i,j) = pt(3) + p1(3);
        end
    end

    surf(ax, X, Y, Z, 'FaceColor', color, 'EdgeColor', 'none');
end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
               app.xEditField.Value = '0';
    app.yEditField.Value = '0';
    app.zEditField.Value = '0';

    app.theta1EditField.Value = '0';
    app.theta2EditField.Value = '0';
    app.theta3EditField.Value = '0';

    % Forward kinematics with default angles
    theta1 = 0;
    theta2 = 0;
    theta3 = 0;

    t1 = deg2rad(theta1);
    t2 = deg2rad(theta2);
    t3 = deg2rad(theta3);

    % Link lengths
    L1 = 1.5;
    L2 = 1.5;
    L3 = 1.5;

    % Transformations
    T01 = [cos(t1), -sin(t1), 0, 0;
           sin(t1),  cos(t1), 0, 0;
           0,        0,       1, L1;
           0,        0,       0, 1];

    T12 = [cos(t2), 0, sin(t2), L2*cos(t2);
           0,       1, 0,       0;
          -sin(t2), 0, cos(t2), L2*sin(t2);
           0,       0, 0,       1];

    T23 = [cos(t3), 0, sin(t3), L3*cos(t3);
           0,       1, 0,       0;
          -sin(t3), 0, cos(t3), L3*sin(t3);
           0,       0, 0,       1];

    T02 = T01 * T12;
    T03 = T02 * T23;

    % Joint positions
    O0 = [0; 0; 0];
    O1 = T01(1:3,4);
    O2 = T02(1:3,4);
    O3 = T03(1:3,4);

    % Update end-effector positions
    app.xEditField.Value = num2str(O3(1), '%.2f');
    app.yEditField.Value = num2str(O3(2), '%.2f');
    app.zEditField.Value = num2str(O3(3), '%.2f');

    % Plot robot arm
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');
    view(app.UIAxes, 3);
    grid(app.UIAxes, 'on');
    xlim(app.UIAxes, [-3 3]);
    ylim(app.UIAxes, [-3 3]);
    zlim(app.UIAxes, [0 4]);
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    zlabel(app.UIAxes, 'Z');

    camlight(app.UIAxes, 'headlight');
    lighting(app.UIAxes, 'gouraud');

    % Plot cylinders
    drawLinkCylinder(app.UIAxes, O0, O1, 0.4, [1 0 0]); % Red
    drawLinkCylinder(app.UIAxes, O1, O2, 0.3, [0 1 0]); % Green
    drawLinkCylinder(app.UIAxes, O2, O3, 0.2, [0 0 1]); % Blue

    % Helper function for cylinders
    function drawLinkCylinder(ax, p1, p2, radius, color)
        h = norm(p2 - p1);
        if h < 1e-6, return; end

        [X, Y, Z] = cylinder(radius, 20);
        Z = Z * h;

        dir = (p2 - p1) / h;
        zAxis = [0; 0; 1];
        v = cross(zAxis, dir);
        s = norm(v);
        c = dot(zAxis, dir);

        if s < 1e-6
            R = eye(3);
        else
            vx = [0, -v(3), v(2); v(3), 0, -v(1); -v(2), v(1), 0];
            R = eye(3) + vx + vx^2 * ((1 - c) / s^2);
        end

        for i = 1:size(X, 1)
            for j = 1:size(X, 2)
                pt = R * [X(i,j); Y(i,j); Z(i,j)];
                X(i,j) = pt(1) + p1(1);
                Y(i,j) = pt(2) + p1(2);
                Z(i,j) = pt(3) + p1(3);
            end
        end

        surf(ax, X, Y, Z, 'FaceColor', color, 'EdgeColor', 'none');
    end
        end

        % Button pushed function: animationwithpenButton
        function animationwithpenButtonPushed(app, event)
          % === Clear the previous plot on the UIAxes ===
cla(app.UIAxes); 

% === Declare persistent variable to store pen trajectory between function calls ===
persistent penTrajectory  % Stores trajectory of end-effector if pen is down

% === Read target joint angles from the UI fields and convert from string to number ===
o1_target = str2double(app.O1EditField.Value);  % Desired angle for joint 1 (in degrees)
o2_target = str2double(app.O2EditField.Value);  % Desired angle for joint 2 (in degrees)
o3_target = str2double(app.O3EditField.Value);  % Desired angle for joint 3 (in degrees)

% === Validate the input to ensure it's numeric ===
if isnan(o1_target) || isnan(o2_target) || isnan(o3_target)
    uialert(app.UIFigure, 'Please enter valid numeric values.', 'Error');  % Display error if input is invalid
    return;  % Exit the function early
end

% === Read the pen state (On/Off) from a GUI switch ===
penDown = strcmp(app.Switch.Value, 'On');  % Returns true if pen is down

% === Define the lengths of the three links of the robot arm ===
L1 = 5; L2 = 4; L3 = 3;  % Lengths of links 1, 2, and 3 respectively

% === Set the number of animation steps for smooth interpolation ===
steps = 50;  % Number of steps for animating motion

% === Generate angle values from 0 to the target angles ===
o1_range = linspace(0, o1_target, steps);  % Joint 1 angle values over time
o2_range = linspace(0, o2_target, steps);  % Joint 2 angle values over time
o3_range = linspace(0, o3_target, steps);  % Joint 3 angle values over time

% === Start animation loop ===
for i = 1:steps
    o1 = o1_range(i);  % Current angle for joint 1
    o2 = o2_range(i);  % Current angle for joint 2
    o3 = o3_range(i);  % Current angle for joint 3

    % === Compute Forward Kinematics to find joint positions ===
    p0 = [0; 0; 0];  % Base position of the robot
    p1 = p0 + [L1*cosd(o1); L1*sind(o1); 0];  % Position of joint 1
    p2 = p1 + [L2*cosd(o1 + o2); L2*sind(o1 + o2); 0];  % Position of joint 2
    p3 = p2 + [L3*cosd(o1 + o2 + o3); L3*sind(o1 + o2 + o3); 0];  % Position of end-effector

    % === Store end-effector position in trajectory if pen is down ===
    if penDown
        penTrajectory = [penTrajectory, p3];  % Add point to trajectory
    else
        penTrajectory = [penTrajectory, [NaN; NaN; NaN]];  % Add a gap in drawing if pen is up
    end

    % === Draw the robot arm and environment ===
    cla(app.UIAxes);  % Clear previous drawing
    hold(app.UIAxes, 'on');  % Allow multiple graphics to be drawn
    axis(app.UIAxes, 'equal');  % Equal scaling for all axes
    grid(app.UIAxes, 'on');  % Display grid
    view(app.UIAxes, 3);  % Set 3D view
    xlabel(app.UIAxes, 'X'); ylabel(app.UIAxes, 'Y'); zlabel(app.UIAxes, 'Z');  % Label axes

    % === Draw the three links of the robot using colored cylinders ===
    r = 0.3;  % Radius of the link cylinders
    drawCylinder(app, p0, p1, r, [1 0 0]);  % Red link from p0 to p1
    drawCylinder(app, p1, p2, r, [0 1 0]);  % Green link from p1 to p2
    drawCylinder(app, p2, p3, r, [0 0 1]);  % Blue link from p2 to p3

    % === Draw the end-effector as a magenta sphere ===
    [sx, sy, sz] = sphere(20);  % Generate a unit sphere mesh
    sR = 0.4;  % Radius of the end-effector sphere
    surf(app.UIAxes, sR*sx + p3(1), sR*sy + p3(2), sR*sz + p3(3), ...
        'FaceColor', 'm', 'EdgeColor', 'none');  % Draw magenta end-effector sphere

    % === Draw the trajectory if it's not empty ===
    if ~isempty(penTrajectory)
        plot3(app.UIAxes, penTrajectory(1,:), penTrajectory(2,:), penTrajectory(3,:), ...
            'k-', 'LineWidth', 2);  % Plot black line showing path of end-effector
    end

    drawnow;  % Force graphics to update
    pause(0.02);  % Delay to control animation speed
end

% === Release hold to clean up UIAxes for next frame ===
hold(app.UIAxes, 'off');
end


    function drawCylinder(app, p1, p2, radius, color)
    h = norm(p2 - p1);
    if h < 1e-6, return; end

    [X, Y, Z] = cylinder(radius, 20);
    Z = Z * h;

    dir = (p2 - p1) / h;
    zAxis = [0; 0; 1];
    v = cross(zAxis, dir);
    s = norm(v);
    c = dot(zAxis, dir);

    if s < 1e-6
        R = eye(3);
    else
        vx = [  0,   -v(3),  v(2);
               v(3),  0,   -v(1);
              -v(2), v(1),   0 ];
        R = eye(3) + vx + vx^2 * ((1 - c) / s^2);
    end

    for i = 1:size(X,1)
        for j = 1:size(X,2)
            pt = R * [X(i,j); Y(i,j); Z(i,j)];
            X(i,j) = pt(1) + p1(1);
            Y(i,j) = pt(2) + p1(2);
            Z(i,j) = pt(3) + p1(3);
        end
    end

    surf(app.UIAxes, X, Y, Z, 'FaceColor', color, 'EdgeColor', 'none');

        end

        % Value changed function: backgroundcolorDropDown
        function backgroundcolorDropDownValueChanged(app, event)
           selectedColor = app.backgroundcolorDropDown.Value;

    switch selectedColor
        case 'White'
            app.UIFigure.Color = [1 1 1];
        case 'Red'
            app.UIFigure.Color = [1 0.4 0.4];
        case 'Green'
            app.UIFigure.Color = [0.6 1 0.6];
        case 'blue'
            app.UIFigure.Color = [0.6 0.8 1];
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.theta1EditFieldLabel = uilabel(app.UIFigure);
            app.theta1EditFieldLabel.Text = 'theta1';
            app.theta1EditField = uieditfield(app.UIFigure, 'text');
            app.theta1EditField.ValueChangingFcn = createCallbackFcn(app, @theta1EditFieldValueChanging, true);
            app.theta2EditFieldLabel = uilabel(app.UIFigure);
            app.theta2EditFieldLabel.Text = 'theta2';
            app.theta2EditField = uieditfield(app.UIFigure, 'text');
            app.theta2EditField.ValueChangingFcn = createCallbackFcn(app, @theta2EditFieldValueChanging, true);
            app.theta3EditFieldLabel = uilabel(app.UIFigure);
            app.theta3EditFieldLabel.Text = 'theta3';
            app.theta3EditField = uieditfield(app.UIFigure, 'text');
            app.theta3EditField.ValueChangingFcn = createCallbackFcn(app, @theta3EditFieldValueChanging, true);
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.xEditField.ValueChangingFcn = createCallbackFcn(app, @xEditFieldValueChanging, true);
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.yEditField.ValueChangingFcn = createCallbackFcn(app, @yEditFieldValueChanging, true);
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            app.zEditField.ValueChangingFcn = createCallbackFcn(app, @zEditFieldValueChanging, true);
            app.forwardButton = uibutton(app.UIFigure, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Text = 'forward';
            app.DOFROBOTICARMLabel = uilabel(app.UIFigure);
            app.DOFROBOTICARMLabel.Text = '3-DOF ROBOTIC ARM';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.ForwardkinematicsLabel = uilabel(app.UIFigure);
            app.ForwardkinematicsLabel.Text = 'Forward kinematics';
            app.inversekinematicsLabel = uilabel(app.UIFigure);
            app.inversekinematicsLabel.Text = 'inverse kinematics';
            app.pxEditFieldLabel = uilabel(app.UIFigure);
            app.pxEditFieldLabel.Text = 'px';
            app.pxEditField = uieditfield(app.UIFigure, 'text');
            app.pyEditFieldLabel = uilabel(app.UIFigure);
            app.pyEditFieldLabel.Text = 'py';
            app.pyEditField = uieditfield(app.UIFigure, 'text');
            app.pzEditFieldLabel = uilabel(app.UIFigure);
            app.pzEditFieldLabel.Text = 'pz';
            app.pzEditField = uieditfield(app.UIFigure, 'text');
            app.inverseButton = uibutton(app.UIFigure, 'push');
            app.inverseButton.ButtonPushedFcn = createCallbackFcn(app, @inverseButtonPushed, true);
            app.inverseButton.Text = 'inverse';
            app.O1EditFieldLabel = uilabel(app.UIFigure);
            app.O1EditFieldLabel.Text = 'O1';
            app.O1EditField = uieditfield(app.UIFigure, 'text');
            app.O2EditFieldLabel = uilabel(app.UIFigure);
            app.O2EditFieldLabel.Text = 'O2';
            app.O2EditField = uieditfield(app.UIFigure, 'text');
            app.O3EditFieldLabel = uilabel(app.UIFigure);
            app.O3EditFieldLabel.Text = 'O3';
            app.O3EditField = uieditfield(app.UIFigure, 'text');
            app.animationButton = uibutton(app.UIFigure, 'push');
            app.animationButton.ButtonPushedFcn = createCallbackFcn(app, @animationButtonPushed, true);
            app.animationButton.Text = 'animation';
            app.AnimationLabel = uilabel(app.UIFigure);
            app.AnimationLabel.Text = 'Animation';
            app.animationwithobstaclesButton = uibutton(app.UIFigure, 'push');
            app.animationwithobstaclesButton.ButtonPushedFcn = createCallbackFcn(app, @animationwithobstaclesButtonPushed, true);
            app.animationwithobstaclesButton.Text = 'animation with obstacles';
            app.animationwithdynamicsButton = uibutton(app.UIFigure, 'push');
            app.animationwithdynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @animationwithdynamicsButtonPushed, true);
            app.animationwithdynamicsButton.Text = 'animation with dynamics';
            app.animationwithpenButton = uibutton(app.UIFigure, 'push');
            app.animationwithpenButton.ButtonPushedFcn = createCallbackFcn(app, @animationwithpenButtonPushed, true);
            app.animationwithpenButton.Text = 'animation with pen';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Inertia2EditFieldLabel = uilabel(app.UIFigure);
            app.Inertia2EditFieldLabel.Text = 'Inertia2';
            app.Inertia2EditField = uieditfield(app.UIFigure, 'text');
            app.Inertia3EditFieldLabel = uilabel(app.UIFigure);
            app.Inertia3EditFieldLabel.Text = 'Inertia3';
            app.Inertia3EditField = uieditfield(app.UIFigure, 'text');
            app.Inertia1EditFieldLabel = uilabel(app.UIFigure);
            app.Inertia1EditFieldLabel.Text = 'Inertia1';
            app.Inertia1EditField = uieditfield(app.UIFigure, 'text');
            app.backgroundcolorDropDownLabel = uilabel(app.UIFigure);
            app.backgroundcolorDropDownLabel.Text = 'backgroundcolor';
            app.backgroundcolorDropDown = uidropdown(app.UIFigure);
            app.backgroundcolorDropDown.Items = {'white', 'Red', 'Green', 'blue'};
            app.backgroundcolorDropDown.ValueChangedFcn = createCallbackFcn(app, @backgroundcolorDropDownValueChanged, true);
            app.backgroundcolorDropDown.Value = 'white';
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end